classdef AcquisitorApp < matlab.apps.AppBase
    %ACQUISITORAPP Camera Control software developed in MATLAB for
    %acquiring images.
    %   This app provides functionalities for:
    %   - Configure Andor and Zelux cameras
    %   - Acquire images with a programmable sequence
    %   - Display real-time analysis

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunNumLabel                     matlab.ui.control.Label
        ElapsedTimeLabel                matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLabel                     matlab.ui.control.Label
        TotalRunTimeLabel               matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ImageTab                        matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        SavecompletedonlyCheckBox       matlab.ui.control.CheckBox
        SmallAxes5ContentDropDown       matlab.ui.control.DropDown
        SmallAxes5IndexDropDown         matlab.ui.control.DropDown
        SmallAxes4ContentDropDown       matlab.ui.control.DropDown
        SmallAxes4IndexDropDown         matlab.ui.control.DropDown
        SmallAxes3ContentDropDown       matlab.ui.control.DropDown
        SmallAxes3IndexDropDown         matlab.ui.control.DropDown
        SmallAxes2ContentDropDown       matlab.ui.control.DropDown
        SmallAxes2IndexDropDown         matlab.ui.control.DropDown
        SmallAxes1ContentDropDown       matlab.ui.control.DropDown
        SmallAxes1IndexDropDown         matlab.ui.control.DropDown
        SAVEButton                      matlab.ui.control.Button
        STOPButton                      matlab.ui.control.StateButton
        PAUSEButton                     matlab.ui.control.StateButton
        RUNButton                       matlab.ui.control.Button
        IndexSpinner                    matlab.ui.control.Spinner
        IndexSpinnerLabel               matlab.ui.control.Label
        BigAxes2AddonDropDown           matlab.ui.control.DropDown
        BigAxes2ContentDropDown         matlab.ui.control.DropDown
        BigAxes2IndexDropDown           matlab.ui.control.DropDown
        BigAxes1AddonDropDown           matlab.ui.control.DropDown
        BigAxes1ContentDropDown         matlab.ui.control.DropDown
        BigAxes1IndexDropDown           matlab.ui.control.DropDown
        SmallAxes5                      matlab.ui.control.UIAxes
        SmallAxes4                      matlab.ui.control.UIAxes
        SmallAxes3                      matlab.ui.control.UIAxes
        SmallAxes2                      matlab.ui.control.UIAxes
        SmallAxes1                      matlab.ui.control.UIAxes
        BigAxes2                        matlab.ui.control.UIAxes
        BigAxes1                        matlab.ui.control.UIAxes
        SettingTab                      matlab.ui.container.Tab
        InstructionsLabel_1             matlab.ui.control.Label
        InstructionsLabel_2             matlab.ui.control.Label
        InstructionsLabel_3             matlab.ui.control.Label
        InstructionsLabel_4             matlab.ui.control.Label
        CameraControlPanel              matlab.ui.container.Panel
        ZeluxPanel                      matlab.ui.container.Panel
        ZeluxMaxQueuedFramesSpinner     matlab.ui.control.Spinner
        ZeluxMaxQueuedFramesSpinnerLabel  matlab.ui.control.Label
        ZeluxCloseButton                matlab.ui.control.Button
        ZeluxConfigureButton            matlab.ui.control.Button
        ZeluxEstimatedreadouttimeLabel  matlab.ui.control.Label
        ZeluxTriggermodeDropDown        matlab.ui.control.DropDown
        ZeluxTriggermodeDropDownLabel   matlab.ui.control.Label
        ZeluxExposuresSpinner           matlab.ui.control.Spinner
        ZeluxExposuresSpinnerLabel      matlab.ui.control.Label
        ZeluxStatusLamp                 matlab.ui.control.Lamp
        Andor19331Panel                 matlab.ui.container.Panel
        Andor19331CloseButton           matlab.ui.control.Button
        Andor19331CheckTemperatureButton  matlab.ui.control.Button
        Andor19331ConfigureButton       matlab.ui.control.Button
        Andor19331TemperatureLabel      matlab.ui.control.Label
        Andor19331EstimatedreadouttimeLabel  matlab.ui.control.Label
        Andor19331ReadoutSpeedDropDown  matlab.ui.control.DropDown
        Andor19331ReadoutSpeedDropDownLabel  matlab.ui.control.Label
        Andor19331TriggermodeDropDown   matlab.ui.control.DropDown
        Andor19331TriggermodeDropDownLabel  matlab.ui.control.Label
        Andor19331AcquisitionmodeDropDown  matlab.ui.control.DropDown
        Andor19331AcquisitionmodeDropDownLabel  matlab.ui.control.Label
        Andor19331ExposuresSpinner      matlab.ui.control.Spinner
        Andor19331ExposuresSpinnerLabel  matlab.ui.control.Label
        Andor19331StatusLamp            matlab.ui.control.Lamp
        Andor19330Panel                 matlab.ui.container.Panel
        Andor19330CloseButton           matlab.ui.control.Button
        Andor19330CheckTemperatureButton  matlab.ui.control.Button
        Andor19330ConfigureButton       matlab.ui.control.Button
        Andor19330TemperatureLabel      matlab.ui.control.Label
        Andor19330EstimatedreadouttimeLabel  matlab.ui.control.Label
        Andor19330ReadoutSpeedDropDown  matlab.ui.control.DropDown
        Andor19330ReadoutSpeedDropDownLabel  matlab.ui.control.Label
        Andor19330TriggermodeDropDown   matlab.ui.control.DropDown
        Andor19330TriggermodeDropDownLabel  matlab.ui.control.Label
        Andor19330AcquisitionmodeDropDown  matlab.ui.control.DropDown
        Andor19330AcquisitionmodeDropDownLabel  matlab.ui.control.Label
        Andor19330ExposuresSpinner      matlab.ui.control.Spinner
        Andor19330ExposuresSpinnerLabel  matlab.ui.control.Label
        Andor19330StatusLamp            matlab.ui.control.Lamp
        OperationControlPanel           matlab.ui.container.Panel
        LoadSequencefromDropDown        matlab.ui.control.DropDown
        LoadSequencefromDropDownLabel   matlab.ui.control.Label
        LoadSettingsfromDatasetButton   matlab.ui.control.Button
        SettingisloadedfromDatasetLabel  matlab.ui.control.Label
        SequenceTable                   matlab.ui.control.Table
        ReplayControlPanel              matlab.ui.container.Panel
        LoadDatasettoReplayButton       matlab.ui.control.Button
        ReplayDatasetLabel              matlab.ui.control.Label
        AcquisitionControlPanel         matlab.ui.container.Panel
        DatasamplingintervalSpinner     matlab.ui.control.Spinner
        RefreshIntervalLabel_2          matlab.ui.control.Label
        AbortacquisitionatendCheckBox   matlab.ui.control.CheckBox
        DropbadframesCheckBox           matlab.ui.control.CheckBox
        NumberofAcqstatisticsSpinner    matlab.ui.control.Spinner
        NumberofLabel                   matlab.ui.control.Label
        RefreshIntervalSpinner          matlab.ui.control.Spinner
        RefreshIntervalLabel            matlab.ui.control.Label
        TimeoutsSpinner                 matlab.ui.control.Spinner
        AcquisitiontimeoutsLabel        matlab.ui.control.Label
        NumberofAcqrawSpinner           matlab.ui.control.Spinner
        NumberofAcqrawSpinnerLabel      matlab.ui.control.Label
        ConfigureAcquisitionButton      matlab.ui.control.Button
        EstimatedDatasetsizeLabel       matlab.ui.control.Label
        OperationModeButtonGroup        matlab.ui.container.ButtonGroup
        ReplayModeButton                matlab.ui.control.RadioButton
        AcquisitionModeButton           matlab.ui.control.RadioButton
        SmallAxes1Menu                  matlab.ui.container.ContextMenu
        SmallAxes1SaveMenu              matlab.ui.container.Menu
        SmallAxes1ClearMenu             matlab.ui.container.Menu
        SmallAxes2Menu                  matlab.ui.container.ContextMenu
        SmallAxes2SaveMenu              matlab.ui.container.Menu
        SmallAxes2ClearMenu             matlab.ui.container.Menu
        SmallAxes3Menu                  matlab.ui.container.ContextMenu
        SmallAxes3SaveMenu              matlab.ui.container.Menu
        SmallAxes3ClearMenu             matlab.ui.container.Menu
        SmallAxes4Menu                  matlab.ui.container.ContextMenu
        SmallAxes4SaveMenu              matlab.ui.container.Menu
        SmallAxes4ClearMenu             matlab.ui.container.Menu
        SmallAxes5Menu                  matlab.ui.container.ContextMenu
        SmallAxes5SaveMenu              matlab.ui.container.Menu
        SmallAxes5ClearMenu             matlab.ui.container.Menu
        BigAxes1Menu                    matlab.ui.container.ContextMenu
        BigAxes1EditcolormapMenu        matlab.ui.container.Menu
        BigAxes2Menu                    matlab.ui.container.ContextMenu
        BigAxes2EditcolormapMenu        matlab.ui.container.Menu
    end

    
    properties (SetAccess = private)
        AcquisitionConfig
        CameraManager
        LayoutManager
        Preprocessor
        Analyzer
        Runner
    end

    methods (Access = private)
        function initAcquisitionMode(app)
            app.Runner = Acquisitor(app.AcquisitionConfig, app.CameraManager, app.LayoutManager, ...
                app.Preprocessor, app.Analyzer);
            app.AcquisitionControlPanel.Enable = "on";
            app.IndexSpinner.Enable = "off";
            app.ReplayControlPanel.Enable = "off";
        end

        function initReplayMode(app)
            app.Runner = Replayer(app.AcquisitionConfig, app.CameraManager, app.LayoutManager, ...
                app.Preprocessor, app.Analyzer);
            app.initReplay()
            app.AcquisitionControlPanel.Enable = "off";
            app.IndexSpinner.Enable = "on";
            app.ReplayControlPanel.Enable = "on";
        end

        function setAcquisition(app)
            app.AcquisitionConfig.config( ...
                "SequenceTable", app.SequenceTable.Data, ...
                "NumAcquisitions", app.NumberofAcqrawSpinner.Value, ...
                "NumStatistics", app.NumberofAcqstatisticsSpinner.Value, ...
                "Refresh", app.RefreshIntervalSpinner.Value, ...
                "Timeout", app.TimeoutsSpinner.Value, ...
                "SampleInterval", app.DatasamplingintervalSpinner.Value, ...
                "AbortAtEnd", app.AbortacquisitionatendCheckBox.Value, ...
                "DropBadFrames", app.DropbadframesCheckBox.Value)
        end

        function getAcquisition(app)
            app.SequenceTable.Data = app.AcquisitionConfig.SequenceTable;
            app.NumberofAcqrawSpinner.Value = app.AcquisitionConfig.NumAcquisitions;
            app.NumberofAcqstatisticsSpinner.Value = app.AcquisitionConfig.NumStatistics;
            app.RefreshIntervalSpinner.Value = app.AcquisitionConfig.Refresh;
            app.TimeoutsSpinner.Value = app.AcquisitionConfig.Timeout;
            app.DatasamplingintervalSpinner.Value = app.AcquisitionConfig.SampleInterval;
            app.AbortacquisitionatendCheckBox.Value = app.AcquisitionConfig.AbortAtEnd;
            app.DropbadframesCheckBox.Value = app.AcquisitionConfig.DropBadFrames;
        end

        function setCameras(app)
            for camera = app.AcquisitionConfig.ActiveCameras
                app.("configure" + camera)()
            end
        end

        function getCameras(app)
            for camera = app.AcquisitionConfig.ActiveCameras
                c = app.CameraManager.(camera);
                app.(camera + "ExposuresSpinner").Value = c.Config.Exposure;
                if c.Config.ExternalTrigger
                    app.(camera + "TriggermodeDropDown").Value = "External";
                else
                    app.(camera + "TriggermodeDropDown").Value = "Internal";
                end
                if class(c.Config) == "AndorCameraConfig"
                    if c.Config.FastKinetic
                        app.(camera + "AcquisitionmodeDropDown").Value = sprintf("FK %d", c.Config.FastKineticSeriesLength);
                    elseif c.Config.Cropped
                        app.(camera + "AcquisitionmodeDropDown").Value = "Cropped 100x1024 1";
                    else
                        app.(camera + "AcquisitionmodeDropDown").Value = "Full Frame 1";
                    end
                    switch c.Config.HSSpeed
                        case 0
                            app.(camera + "ReadoutSpeedDropDown").Value = "5MHz";
                        case 1
                            app.(camera + "ReadoutSpeedDropDown").Value = "3MHz";
                        case 2
                            app.(camera + "ReadoutSpeedDropDown").Value = "1MHz";
                    end
                elseif class(c.Config) == "ZeluxCameraConfig"
                    app.ZeluxMaxQueuedFramesSpinner.Value = c.Config.MaxQueuedFrames;
                end
                app.("update" + camera + "Status")()
            end
        end
        
        function initAcquisition(app)
            app.setAcquisition()
            % Update index drop-downs from AcquisitionConfig
            image_list = app.AcquisitionConfig.ImageList;
            for field = app.LayoutManager.VisibleProp
                app.(field + "IndexDropDown").Items = image_list;
                app.(field + "IndexDropDownValueChanged")()
            end
            % Configure cameras to the selected settings
            app.setCameras()
            % Initialize storage etc.
            app.Runner.init()
            app.EstimatedDatasetsizeLabel.Text = sprintf('Estimated Dataset size: %g MB', ...
                app.Runner.DataStorage.MemoryUsage);
        end

        function initReplay(app)
            app.getAcquisition()
            app.getCameras()
            app.ReplayDatasetLabel.Text = sprintf("Replay Dataset is loaded from:\n %s", app.Runner.DataPath);
            app.IndexSpinner.Limits = [1, app.AcquisitionConfig.NumAcquisitions];
            app.IndexSpinnerValueChanged()
            % Update index drop-downs from AcquisitionConfig
            image_list = app.AcquisitionConfig.ImageList;
            for field = app.LayoutManager.VisibleProp
                app.(field + "IndexDropDown").Items = image_list;
                app.(field + "IndexDropDownValueChanged")()
            end
        end

        function args = parseAndorConfigFromPanel(app, camera)
            args = {"Exposure", app.(camera + "ExposuresSpinner").Value, ...
                    "ExternalTrigger", app.(camera + "TriggermodeDropDown").Value == "External"};
            switch app.(camera + "ReadoutSpeedDropDown").Value
                case "1MHz"
                    args = [args, {"HSSpeed", 2}];
                case "3MHz"
                    args = [args, {"HSSpeed", 1}];
                case "5MHz"
                    args = [args, {"HSSpeed", 0}];
            end
            if app.(camera + "AcquisitionmodeDropDown").Value == "Cropped 100x1024 1"
                args = [args, {"Cropped", true, "XPixels", 100, "YPixels", 1024}];
            else
                args = [args, {"Cropped", false}];
            end
            args = [args, {"FastKinetic", strcmp(app.(camera + "AcquisitionmodeDropDown").Value(1:2), 'FK'), ...
                           "FastKineticSeriesLength", str2double(app.(camera + "AcquisitionmodeDropDown").Value(end))}];
        end

        function SmallAxesIndexDropDownValueChanged(app, ax_name)
            index_dropdown = app.(ax_name + "IndexDropDown");
            content_dropdown = app.(ax_name + "ContentDropDown");
            content_dropdown.Items = [AppConfig.BaseAnalysis, ...
                app.AcquisitionConfig.parseAnalysis2Content(index_dropdown.Value)];
            app.SmallAxesContentDropDownValueChanged(ax_name)            
        end

        function SmallAxesContentDropDownValueChanged(app, ax_name)
            index_dropdown = app.(ax_name + "IndexDropDown");
            content_dropdown = app.(ax_name + "ContentDropDown");
            res = split(index_dropdown.Value, ": ");
            con = split(content_dropdown.Value, ": ");
            app.LayoutManager.(ax_name).config("CameraName", res{1}, ...
                                               "ImageLabel", res{2}, ...
                                               "Content", con{1}, ...
                                               "FuncName", con{2})
        end

        function BigAxesIndexDropDownValueChanged(app, ax_name)
            index_dropdown = app.(ax_name + "IndexDropDown");
            content_dropdown = app.(ax_name + "ContentDropDown");
            addon_dropdown = app.(ax_name + "AddonDropDown");
            res = split(index_dropdown.Value, ": ");
            app.LayoutManager.(ax_name).config("CameraName", res{1}, ...
                                               "ImageLabel", res{2}, ...
                                               "Content", content_dropdown.Value, ...
                                               "FuncName", addon_dropdown.Value)
        end

        function cleanUpRun(app)
            app.CameraManager.abortAcquisition(app.AcquisitionConfig.ActiveCameras);
            app.RUNButton.Enable = true;
            app.SAVEButton.Enable = true;
            if app.PAUSEButton.Value
                app.PAUSEButton.Value = false;
                app.StatusLamp.Color = [1, 1, 0];
                app.StatusLabel.Text = 'Paused';
            else
                app.STOPButton.Value = false;
                app.StatusLamp.Color = [1, 0, 0];
                app.StatusLabel.Text = 'Stopped';
                app.CameraControlPanel.Enable = "on";
                app.OperationControlPanel.Enable = "on";
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startApp(app)
            clc
            assignin("base", "app", app)
            
            app.UIFigure.Position = AppConfig.FigurePosition;

            % Initialize sequence table data
            app.LoadSequencefromDropDown.Items = properties(SequenceRegistry);
            app.LoadSequencefromDropDown.Value = AppConfig.SequenceName;
            app.LoadSequencefromDropDownValueChanged()
            
            app.CameraManager = CameraManager('test_mode', AppConfig.TestMode); %#ok<ADPROP>
            app.LayoutManager = LayoutManager(app); %#ok<ADPROP>
            app.AcquisitionConfig = AcquisitionConfig(); %#ok<ADPROP>
            app.Preprocessor = Preprocessor(); %#ok<ADPROP>
            app.Analyzer = Analyzer(); %#ok<ADPROP>
            
            % Update runner
            app.OperationModeButtonGroupSelectionChanged()
        end

        % Button pushed function: RUNButton
        function run(app, event)
            c_obj = onCleanup(@()app.cleanUpRun);
            if app.StatusLabel.Text == "Stopped" && isa(app.Runner, "Acquisitor")
                app.initAcquisition()
            end
            app.RUNButton.Enable = "off";
            app.SAVEButton.Enable = "off";
            app.CameraControlPanel.Enable = false;
            app.OperationControlPanel.Enable = false;
            while ~app.STOPButton.Value && ~app.PAUSEButton.Value
                timer_run = tic;
                app.StatusLamp.Color = [0, 1, 1];
                app.StatusLabel.Text = 'Acquiring......';
                
                app.Runner.run()

                app.StatusLamp.Color = [0, 1, 0];
                app.StatusLabel.Text = 'Acquired';
                app.TotalRunTimeLabel.Text = sprintf('Total Run Time: %.2f min', toc(app.Runner.Timer)/60);
                app.ElapsedTimeLabel.Text = sprintf('Elapsed time: %.2f s', toc(timer_run));
                app.RunNumLabel.Text = sprintf('RunNum: %d, Saved: %d/%d (Data), %d/%d (Stat)', ...
                    app.Runner.Live.RunNumber, ...
                    app.Runner.DataStorage.CurrentIndex, ...
                    app.AcquisitionConfig.NumAcquisitions, ...
                    app.Runner.StatStorage.CurrentIndex, ...
                    app.AcquisitionConfig.NumStatistics);
            end
        end

        % Value changed function: IndexSpinner
        function IndexSpinnerValueChanged(app, event)
            if app.IndexSpinner.Value > 0
                app.Runner.config("CurrentIndex", app.IndexSpinner.Value)
                app.Runner.run()
            end
        end

        % Button pushed function: SAVEButton
        function saveData(app, event)
            try
                Data = app.Runner.DataStorage.struct("check_incomplete", true, "completed_only", app.SavecompletedonlyCheckBox.Value); 
                Stat = app.Runner.StatStorage.struct("check_incomplete", true, "completed_only", app.SavecompletedonlyCheckBox.Value);
                if app.Runner.DataStorage.MemoryUsage > 2048
                    [filename, pathname] = uigetfile('*.mat', 'Save workspace variables in v7.3 format');
                    if ~isequal(filename, 0)
                        save(fullfile(pathname, filename), "Data", "Stat", '-v7.3')
                        uialert(app.UIFigure, "Dataset saved to file in v7.3 format!", "Alert")
                    end
                else
                    uisave({"Data", "Stat"}, "Data.mat")
                    uialert(app.UIFigure, "Dataset saved to file!", "Alert")
                end
            catch me
                warning(getReport(me, 'extended', 'hyperlinks', 'on'))
            end
        end

        % Value changed function: BigAxes1AddonDropDown, 
        % ...and 2 other components
        function BigAxes1IndexDropDownValueChanged(app, event)
            app.BigAxesIndexDropDownValueChanged("BigAxes1")
        end

        % Value changed function: BigAxes2AddonDropDown, 
        % ...and 2 other components
        function BigAxes2IndexDropDownValueChanged(app, event)
            app.BigAxesIndexDropDownValueChanged("BigAxes2")
        end

        % Value changed function: SmallAxes1IndexDropDown
        function SmallAxes1IndexDropDownValueChanged(app, event)
            app.SmallAxesIndexDropDownValueChanged("SmallAxes1")
        end

        % Value changed function: SmallAxes1ContentDropDown
        function SmallAxes1ContentDropDownValueChanged(app, event)
            app.SmallAxesContentDropDownValueChanged("SmallAxes1")
        end

        % Value changed function: SmallAxes2IndexDropDown
        function SmallAxes2IndexDropDownValueChanged(app, event)
            app.SmallAxesIndexDropDownValueChanged("SmallAxes2")
        end

        % Value changed function: SmallAxes2ContentDropDown
        function SmallAxes2ContentDropDownValueChanged(app, event)
            app.SmallAxesContentDropDownValueChanged("SmallAxes2")
        end

        % Value changed function: SmallAxes3IndexDropDown
        function SmallAxes3IndexDropDownValueChanged(app, event)
            app.SmallAxesIndexDropDownValueChanged("SmallAxes3")
        end

        % Value changed function: SmallAxes3ContentDropDown
        function SmallAxes3ContentDropDownValueChanged(app, event)
            app.SmallAxesContentDropDownValueChanged("SmallAxes3")
        end

        % Value changed function: SmallAxes4IndexDropDown
        function SmallAxes4IndexDropDownValueChanged(app, event)
            app.SmallAxesIndexDropDownValueChanged("SmallAxes4")
        end

        % Value changed function: SmallAxes4ContentDropDown
        function SmallAxes4ContentDropDownValueChanged(app, event)
            app.SmallAxesContentDropDownValueChanged("SmallAxes4")
        end

        % Value changed function: SmallAxes5IndexDropDown
        function SmallAxes5IndexDropDownValueChanged(app, event)
            app.SmallAxesIndexDropDownValueChanged("SmallAxes5")
        end

        % Value changed function: SmallAxes5ContentDropDown
        function SmallAxes5ContentDropDownValueChanged(app, event)
            app.SmallAxesContentDropDownValueChanged("SmallAxes5")
        end

        % Menu selected function: SmallAxes1SaveMenu
        function SmallAxes1SaveMenuSelected(app, event)
            app.LayoutManager.SmallAxes1.uisave()
        end

        % Menu selected function: SmallAxes1ClearMenu
        function SmallAxes1ClearMenuSelected(app, event)
            app.LayoutManager.SmallAxes1.clear()
        end

        % Menu selected function: SmallAxes2SaveMenu
        function SmallAxes2SaveMenuSelected(app, event)
            app.LayoutManager.SmallAxes2.uisave()
        end

        % Menu selected function: SmallAxes2ClearMenu
        function SmallAxes2ClearMenuSelected(app, event)
            app.LayoutManager.SmallAxes2.clear()
        end

        % Menu selected function: SmallAxes3SaveMenu
        function SmallAxes3SaveMenuSelected(app, event)
            app.LayoutManager.SmallAxes3.uisave()
        end

        % Menu selected function: SmallAxes3ClearMenu
        function SmallAxes3ClearMenuSelected(app, event)
            app.LayoutManager.SmallAxes3.clear()
        end

        % Menu selected function: SmallAxes4SaveMenu
        function SmallAxes4SaveMenuSelected(app, event)
            app.LayoutManager.SmallAxes4.uisave()
        end

        % Menu selected function: SmallAxes4ClearMenu
        function SmallAxes4ClearMenuSelected(app, event)
            app.LayoutManager.SmallAxes4.clear()
        end

        % Menu selected function: SmallAxes5SaveMenu
        function SmallAxes5SaveMenuSelected(app, event)
            app.LayoutManager.SmallAxes5.uisave()
        end

        % Menu selected function: SmallAxes5ClearMenu
        function SmallAxes5ClearMenuSelected(app, event)
            app.LayoutManager.SmallAxes5.clear()
        end

        % Value changed function: Andor19330AcquisitionmodeDropDown, 
        % ...and 3 other components
        function updateAndor19330Status(app, event)
            % Setting the status to "Yellow" to indicate the settings are
            % not active
            if isequal(app.Andor19330StatusLamp.Color, [0, 1, 0])
                app.Andor19330StatusLamp.Color = [1, 1, 0];
            end
        end

        % Value changed function: Andor19331AcquisitionmodeDropDown, 
        % ...and 3 other components
        function updateAndor19331Status(app, event)
            % Setting the status to "Yellow" to indicate the settings are
            % not active
            if isequal(app.Andor19331StatusLamp.Color, [0, 1, 0])
                app.Andor19331StatusLamp.Color = [1, 1, 0];
            end
        end

        % Value changed function: ZeluxExposuresSpinner, 
        % ...and 2 other components
        function updateZeluxStatus(app, event)
            % Setting the status to "Yellow" to indicate the settings are
            % not active
            if isequal(app.ZeluxStatusLamp.Color, [0, 1, 0])
                app.ZeluxStatusLamp.Color = [1, 1, 0];
            end
        end

        % Button pushed function: Andor19330ConfigureButton
        function configureAndor19330(app, event)
            args = app.parseAndorConfigFromPanel("Andor19330");
            app.CameraManager.Andor19330.init()
            app.CameraManager.Andor19330.config(args{:})
            [~, readout_time] = app.CameraManager.Andor19330.getTimings();
            app.Andor19330StatusLamp.Color = [0, 1, 0];
            app.Andor19330EstimatedreadouttimeLabel.Text = sprintf("Estimated readout time: %11.4f s", readout_time);
        end

        % Button pushed function: Andor19331ConfigureButton
        function configureAndor19331(app, event)
            args = app.parseAndorConfigFromPanel("Andor19331");
            app.CameraManager.Andor19331.init()
            app.CameraManager.Andor19331.config(args{:})
            [~, readout_time] = app.CameraManager.Andor19331.getTimings();
            app.Andor19331StatusLamp.Color = [0, 1, 0];
            app.Andor19331EstimatedreadouttimeLabel.Text = sprintf("Estimated readout time: %11.4f s", readout_time);
        end

        % Button pushed function: ZeluxConfigureButton
        function configureZelux(app, event)
            args = {"Exposure", app.ZeluxExposuresSpinner.Value, ...
                    "ExternalTrigger", app.ZeluxTriggermodeDropDown.Value == "External", ...
                    "MaxQueuedFrames", app.ZeluxMaxQueuedFramesSpinner.Value};
            app.CameraManager.Zelux.init()
            app.CameraManager.Zelux.config(args{:})
            [~, readout_time] = app.CameraManager.Zelux.getTimings();
            app.ZeluxStatusLamp.Color = [0, 1, 0];
            app.ZeluxEstimatedreadouttimeLabel.Text = sprintf("Estimated readout time: %11.4f s", readout_time);
        end

        % Button pushed function: Andor19330CheckTemperatureButton
        function checkTemperatureAndor19330(app, event)
            [~, temp, status] = app.CameraManager.Andor19330.checkTemperature();
            app.Andor19330TemperatureLabel.Text = sprintf("Current Temperature: %.3g\n%s", temp, status);
        end

        % Button pushed function: Andor19331CheckTemperatureButton
        function checkTemperatureAndor19331(app, event)
            [~, temp, status] = app.CameraManager.Andor19331.checkTemperature();
            app.Andor19331TemperatureLabel.Text = sprintf("Current Temperature: %.3g\n%s", temp, status);
        end

        % Button pushed function: Andor19330CloseButton
        function closeAndor19330(app, event)
            app.CameraManager.Andor19330.close()
            app.Andor19330StatusLamp.Color = [1, 0, 0];
        end

        % Button pushed function: Andor19331CloseButton
        function closeAndor19331(app, event)
            app.CameraManager.Andor19331.close()
            app.Andor19331StatusLamp.Color = [1, 0, 0];
        end

        % Button pushed function: ZeluxCloseButton
        function closeZelux(app, event)
            app.CameraManager.Zelux.close()
            app.ZeluxStatusLamp.Color = [1, 0, 0];
        end

        % Value changed function: LoadSequencefromDropDown
        function LoadSequencefromDropDownValueChanged(app, event)
            app.SequenceTable.Data = SequenceRegistry.(app.LoadSequencefromDropDown.Value);            
        end

        % Button pushed function: LoadSettingsfromDatasetButton
        function LoadSettingsfromDatasetButtonPushed(app, event)
            [file, location] = uigetfile('*.mat', 'Select a dataset');
            if file ~= 0
                app.Runner.config(load(fullfile(location, file)).Data)
                % [app.Runner, app.AcquisitionConfig, app.CameraManager] = Acquisitor.struct2obj(load(fullfile(location, file)).Data, ...
                %     app.LayoutManager, app.Preprocessor, app.Analyzer, "test_mode", AppConfig.TestMode);
                app.getAcquisition()
                app.getCameras()
                app.SettingisloadedfromDatasetLabel.Text = sprintf("Setting is loaded from Dataset:\n%s", fullfile(location, file));
                app.EstimatedDatasetsizeLabel.Text = sprintf('Estimated Dataset size: %g MB', ...
                    app.Runner.DataStorage.MemoryUsage);
            end
        end

        % Button pushed function: ConfigureAcquisitionButton
        function ConfigureAcquisitionButtonPushed(app, event)
            app.setAcquisition()
            app.Runner.DataStorage.init()
            app.EstimatedDatasetsizeLabel.Text = sprintf('Estimated Dataset size: %g MB', ...
                app.Runner.DataStorage.MemoryUsage);
        end

        % Button pushed function: LoadDatasettoReplayButton
        function LoadDatasettoReplayButtonPushed(app, event)
            [file, location] = uigetfile('*.mat', 'Select a dataset');
            if file ~= 0
                app.Runner.config("DataPath", fullfile(location, file))
                app.initReplay()
            end
        end

        % Selection changed function: OperationModeButtonGroup
        function OperationModeButtonGroupSelectionChanged(app, event)
            selected_button = app.OperationModeButtonGroup.SelectedObject;
            switch selected_button
                case app.AcquisitionModeButton
                    app.initAcquisitionMode()
                case app.ReplayModeButton
                    app.initReplayMode()
            end
        end

        % Menu selected function: BigAxes1EditcolormapMenu
        function BigAxes1EditcolormapMenuSelected(app, event)
            colormapeditor(app.BigAxes1)
        end

        % Menu selected function: BigAxes2EditcolormapMenu
        function BigAxes2EditcolormapMenuSelected(app, event)
            colormapeditor(app.BigAxes2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.GridLayout = uigridlayout(app.ImageTab);
            app.BigAxes1 = uiaxes(app.GridLayout);
            app.BigAxes2 = uiaxes(app.GridLayout);
            title(app.BigAxes2, ' ')
            app.SmallAxes1 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes1, 'Run Number')
            zlabel(app.SmallAxes1, 'Z')
            app.SmallAxes2 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes2, 'Run Number')
            zlabel(app.SmallAxes2, 'Z')
            app.SmallAxes3 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes3, 'Run Number')
            zlabel(app.SmallAxes3, 'Z')
            app.SmallAxes3.Tag = 'SmallAxes3';
            app.SmallAxes4 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes4, 'Run Number')
            zlabel(app.SmallAxes4, 'Z')
            app.SmallAxes4.Tag = 'SmallAxes3';
            app.SmallAxes5 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes5, 'Run Number')
            zlabel(app.SmallAxes5, 'Z')
            app.SmallAxes5.Tag = 'SmallAxes3';
            app.BigAxes1IndexDropDown = uidropdown(app.GridLayout);
            app.BigAxes1IndexDropDown.Items = {'Andor19330: Image'};
            app.BigAxes1IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @BigAxes1IndexDropDownValueChanged, true);
            app.BigAxes1IndexDropDown.Placeholder = 'Camera: Label';
            app.BigAxes1IndexDropDown.Value = 'Andor19330: Image';
            app.BigAxes1ContentDropDown = uidropdown(app.GridLayout);
            app.BigAxes1ContentDropDown.Items = {'Signal', 'Raw', 'Background'};
            app.BigAxes1ContentDropDown.ValueChangedFcn = createCallbackFcn(app, @BigAxes1IndexDropDownValueChanged, true);
            app.BigAxes1ContentDropDown.Value = 'Signal';
            app.BigAxes1AddonDropDown = uidropdown(app.GridLayout);
            app.BigAxes1AddonDropDown.Items = {'None', 'Lattice', 'Lattice All'};
            app.BigAxes1AddonDropDown.ValueChangedFcn = createCallbackFcn(app, @BigAxes1IndexDropDownValueChanged, true);
            app.BigAxes1AddonDropDown.Placeholder = 'Addon';
            app.BigAxes1AddonDropDown.Value = 'None';
            app.BigAxes2IndexDropDown = uidropdown(app.GridLayout);
            app.BigAxes2IndexDropDown.Items = {'Andor19331: Image'};
            app.BigAxes2IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @BigAxes2IndexDropDownValueChanged, true);
            app.BigAxes2IndexDropDown.Placeholder = 'Camera: Label';
            app.BigAxes2IndexDropDown.Value = 'Andor19331: Image';
            app.BigAxes2ContentDropDown = uidropdown(app.GridLayout);
            app.BigAxes2ContentDropDown.Items = {'Signal', 'Raw', 'Background'};
            app.BigAxes2ContentDropDown.ValueChangedFcn = createCallbackFcn(app, @BigAxes2IndexDropDownValueChanged, true);
            app.BigAxes2ContentDropDown.Value = 'Signal';
            app.BigAxes2AddonDropDown = uidropdown(app.GridLayout);
            app.BigAxes2AddonDropDown.Items = {'None', 'Lattice', 'Lattice All'};
            app.BigAxes2AddonDropDown.ValueChangedFcn = createCallbackFcn(app, @BigAxes2IndexDropDownValueChanged, true);
            app.BigAxes2AddonDropDown.Placeholder = 'Addon';
            app.BigAxes2AddonDropDown.Value = 'None';
            app.IndexSpinnerLabel = uilabel(app.GridLayout);
            app.IndexSpinnerLabel.Text = 'Index';
            app.IndexSpinner = uispinner(app.GridLayout);
            app.IndexSpinner.Limits = [1 Inf];
            app.IndexSpinner.RoundFractionalValues = 'on';
            app.IndexSpinner.ValueDisplayFormat = '%d';
            app.IndexSpinner.ValueChangedFcn = createCallbackFcn(app, @IndexSpinnerValueChanged, true);
            app.IndexSpinner.Enable = 'off';
            app.IndexSpinner.Value = 1;
            app.RUNButton = uibutton(app.GridLayout, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.RUNButton.Text = 'RUN';
            app.PAUSEButton = uibutton(app.GridLayout, 'state');
            app.PAUSEButton.Text = 'PAUSE';
            app.STOPButton = uibutton(app.GridLayout, 'state');
            app.STOPButton.Text = 'STOP';
            app.SAVEButton = uibutton(app.GridLayout, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @saveData, true);
            app.SAVEButton.Text = 'SAVE';
            app.SmallAxes1IndexDropDown = uidropdown(app.GridLayout);
            app.SmallAxes1IndexDropDown.Items = {'Andor19330: Image'};
            app.SmallAxes1IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @SmallAxes1IndexDropDownValueChanged, true);
            app.SmallAxes1IndexDropDown.Placeholder = 'Camera: Label';
            app.SmallAxes1IndexDropDown.Value = 'Andor19330: Image';
            app.SmallAxes1ContentDropDown = uidropdown(app.GridLayout);
            app.SmallAxes1ContentDropDown.Items = {'Signal: Max'};
            app.SmallAxes1ContentDropDown.ValueChangedFcn = createCallbackFcn(app, @SmallAxes1ContentDropDownValueChanged, true);
            app.SmallAxes1ContentDropDown.Placeholder = 'Content: Function';
            app.SmallAxes1ContentDropDown.Value = 'Signal: Max';
            app.SmallAxes2IndexDropDown = uidropdown(app.GridLayout);
            app.SmallAxes2IndexDropDown.Items = {'Andor19330: Image'};
            app.SmallAxes2IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @SmallAxes2IndexDropDownValueChanged, true);
            app.SmallAxes2IndexDropDown.Placeholder = 'Camera: Label';
            app.SmallAxes2IndexDropDown.Value = 'Andor19330: Image';
            app.SmallAxes2ContentDropDown = uidropdown(app.GridLayout);
            app.SmallAxes2ContentDropDown.Items = {'Signal: Max'};
            app.SmallAxes2ContentDropDown.ValueChangedFcn = createCallbackFcn(app, @SmallAxes2ContentDropDownValueChanged, true);
            app.SmallAxes2ContentDropDown.Placeholder = 'Content: Function';
            app.SmallAxes2ContentDropDown.Value = 'Signal: Max';
            app.SmallAxes3IndexDropDown = uidropdown(app.GridLayout);
            app.SmallAxes3IndexDropDown.Items = {'Andor19331: Image'};
            app.SmallAxes3IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @SmallAxes3IndexDropDownValueChanged, true);
            app.SmallAxes3IndexDropDown.Placeholder = 'Camera: Label';
            app.SmallAxes3IndexDropDown.Value = 'Andor19331: Image';
            app.SmallAxes3ContentDropDown = uidropdown(app.GridLayout);
            app.SmallAxes3ContentDropDown.Items = {'Signal: Max'};
            app.SmallAxes3ContentDropDown.ValueChangedFcn = createCallbackFcn(app, @SmallAxes3ContentDropDownValueChanged, true);
            app.SmallAxes3ContentDropDown.Placeholder = 'Content: Function';
            app.SmallAxes3ContentDropDown.Value = 'Signal: Max';
            app.SmallAxes4IndexDropDown = uidropdown(app.GridLayout);
            app.SmallAxes4IndexDropDown.Items = {'Andor19331: Image'};
            app.SmallAxes4IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @SmallAxes4IndexDropDownValueChanged, true);
            app.SmallAxes4IndexDropDown.Placeholder = 'Camera: Label';
            app.SmallAxes4IndexDropDown.Value = 'Andor19331: Image';
            app.SmallAxes4ContentDropDown = uidropdown(app.GridLayout);
            app.SmallAxes4ContentDropDown.Items = {'Signal: Max'};
            app.SmallAxes4ContentDropDown.ValueChangedFcn = createCallbackFcn(app, @SmallAxes4ContentDropDownValueChanged, true);
            app.SmallAxes4ContentDropDown.Placeholder = 'Content: Function';
            app.SmallAxes4ContentDropDown.Value = 'Signal: Max';
            app.SmallAxes5IndexDropDown = uidropdown(app.GridLayout);
            app.SmallAxes5IndexDropDown.Items = {'Zelux: Lattice'};
            app.SmallAxes5IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @SmallAxes5IndexDropDownValueChanged, true);
            app.SmallAxes5IndexDropDown.Placeholder = 'Camera: Label';
            app.SmallAxes5IndexDropDown.Value = 'Zelux: Lattice';
            app.SmallAxes5ContentDropDown = uidropdown(app.GridLayout);
            app.SmallAxes5ContentDropDown.Items = {'Signal: Max'};
            app.SmallAxes5ContentDropDown.ValueChangedFcn = createCallbackFcn(app, @SmallAxes5ContentDropDownValueChanged, true);
            app.SmallAxes5ContentDropDown.Placeholder = 'Content: Function';
            app.SmallAxes5ContentDropDown.Value = 'Signal: Max';
            app.SavecompletedonlyCheckBox = uicheckbox(app.GridLayout);
            app.SavecompletedonlyCheckBox.Text = 'Save completed only';
            app.SavecompletedonlyCheckBox.Value = true;
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.OperationControlPanel = uipanel(app.SettingTab);
            app.OperationControlPanel.Title = 'Operation Control';
            app.OperationModeButtonGroup = uibuttongroup(app.OperationControlPanel);
            app.OperationModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperationModeButtonGroupSelectionChanged, true);
            app.AcquisitionModeButton = uiradiobutton(app.OperationModeButtonGroup);
            app.AcquisitionModeButton.Text = 'Acquisition';
            app.AcquisitionModeButton.Value = true;
            app.ReplayModeButton = uiradiobutton(app.OperationModeButtonGroup);
            app.ReplayModeButton.Text = 'Replay';
            app.AcquisitionControlPanel = uipanel(app.OperationControlPanel);
            app.AcquisitionControlPanel.Title = 'Acquisition Control';
            app.EstimatedDatasetsizeLabel = uilabel(app.AcquisitionControlPanel);
            app.EstimatedDatasetsizeLabel.Text = 'Estimated Dataset size: ';
            app.ConfigureAcquisitionButton = uibutton(app.AcquisitionControlPanel, 'push');
            app.ConfigureAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureAcquisitionButtonPushed, true);
            app.ConfigureAcquisitionButton.Text = 'Configure Acquisition';
            app.NumberofAcqrawSpinnerLabel = uilabel(app.AcquisitionControlPanel);
            app.NumberofAcqrawSpinnerLabel.Text = 'Number of Acq. (raw)';
            app.NumberofAcqrawSpinner = uispinner(app.AcquisitionControlPanel);
            app.NumberofAcqrawSpinner.Limits = [1 Inf];
            app.NumberofAcqrawSpinner.RoundFractionalValues = 'on';
            app.NumberofAcqrawSpinner.ValueDisplayFormat = '%d';
            app.NumberofAcqrawSpinner.Value = 20;
            app.AcquisitiontimeoutsLabel = uilabel(app.AcquisitionControlPanel);
            app.AcquisitiontimeoutsLabel.Text = 'Timeout (s)';
            app.TimeoutsSpinner = uispinner(app.AcquisitionControlPanel);
            app.TimeoutsSpinner.Limits = [0 Inf];
            app.TimeoutsSpinner.Value = Inf;
            app.RefreshIntervalLabel = uilabel(app.AcquisitionControlPanel);
            app.RefreshIntervalLabel.Text = 'Refresh interval (s)';
            app.RefreshIntervalSpinner = uispinner(app.AcquisitionControlPanel);
            app.RefreshIntervalSpinner.Step = 0.005;
            app.RefreshIntervalSpinner.Limits = [0 Inf];
            app.RefreshIntervalSpinner.Value = 0.01;
            app.NumberofLabel = uilabel(app.AcquisitionControlPanel);
            app.NumberofLabel.Text = 'Number of Acq. (statistics)';
            app.NumberofAcqstatisticsSpinner = uispinner(app.AcquisitionControlPanel);
            app.NumberofAcqstatisticsSpinner.Limits = [1 Inf];
            app.NumberofAcqstatisticsSpinner.RoundFractionalValues = 'on';
            app.NumberofAcqstatisticsSpinner.ValueDisplayFormat = '%d';
            app.NumberofAcqstatisticsSpinner.Value = 1000;
            app.DropbadframesCheckBox = uicheckbox(app.AcquisitionControlPanel);
            app.DropbadframesCheckBox.Text = 'Drop bad frames';
            app.DropbadframesCheckBox.Value = true;
            app.AbortacquisitionatendCheckBox = uicheckbox(app.AcquisitionControlPanel);
            app.AbortacquisitionatendCheckBox.Text = 'Abort acquisition at end';
            app.AbortacquisitionatendCheckBox.Value = true;
            app.RefreshIntervalLabel_2 = uilabel(app.AcquisitionControlPanel);
            app.RefreshIntervalLabel_2.Text = 'Data sampling interval ';
            app.DatasamplingintervalSpinner = uispinner(app.AcquisitionControlPanel);
            app.DatasamplingintervalSpinner.Limits = [1 Inf];
            app.DatasamplingintervalSpinner.RoundFractionalValues = 'on';
            app.DatasamplingintervalSpinner.ValueDisplayFormat = '%d';
            app.DatasamplingintervalSpinner.Value = 1;
            app.ReplayControlPanel = uipanel(app.OperationControlPanel);
            app.ReplayControlPanel.Enable = 'off';
            app.ReplayControlPanel.Title = 'Replay Control';
            app.ReplayDatasetLabel = uilabel(app.ReplayControlPanel);
            app.ReplayDatasetLabel.Text = {'Replay Dataset is loaded from: '; ''};
            app.LoadDatasettoReplayButton = uibutton(app.ReplayControlPanel, 'push');
            app.LoadDatasettoReplayButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasettoReplayButtonPushed, true);
            app.LoadDatasettoReplayButton.Text = 'Load Dataset to Replay';
            app.SequenceTable = uitable(app.OperationControlPanel);
            app.SequenceTable.ColumnName = {'Order'; 'Camera'; 'Label'; 'Type'; 'Note'};
            app.SequenceTable.RowName = {};
            app.SequenceTable.ColumnEditable = [false true true true true];
            app.SequenceTable.Multiselect = 'off';
            app.SettingisloadedfromDatasetLabel = uilabel(app.OperationControlPanel);
            app.SettingisloadedfromDatasetLabel.Text = 'Setting is loaded from Dataset: ';
            app.LoadSettingsfromDatasetButton = uibutton(app.OperationControlPanel, 'push');
            app.LoadSettingsfromDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsfromDatasetButtonPushed, true);
            app.LoadSettingsfromDatasetButton.Text = 'Load Settings from Dataset';
            app.LoadSequencefromDropDownLabel = uilabel(app.OperationControlPanel);
            app.LoadSequencefromDropDownLabel.Text = 'Load Sequence from';
            app.LoadSequencefromDropDown = uidropdown(app.OperationControlPanel);
            app.LoadSequencefromDropDown.Items = {};
            app.LoadSequencefromDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadSequencefromDropDownValueChanged, true);
            app.LoadSequencefromDropDown.Value = {};
            app.CameraControlPanel = uipanel(app.SettingTab);
            app.CameraControlPanel.Title = 'Camera Control';
            app.Andor19330Panel = uipanel(app.CameraControlPanel);
            app.Andor19330Panel.Title = 'Andor 19330';
            app.Andor19330StatusLamp = uilamp(app.Andor19330Panel);
            app.Andor19330ExposuresSpinnerLabel = uilabel(app.Andor19330Panel);
            app.Andor19330ExposuresSpinnerLabel.Text = 'Exposure (s)';
            app.Andor19330ExposuresSpinner = uispinner(app.Andor19330Panel);
            app.Andor19330ExposuresSpinner.Step = 0.1;
            app.Andor19330ExposuresSpinner.Limits = [0 Inf];
            app.Andor19330ExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330ExposuresSpinner.Value = 0.2;
            app.Andor19330AcquisitionmodeDropDownLabel = uilabel(app.Andor19330Panel);
            app.Andor19330AcquisitionmodeDropDownLabel.Text = 'Acquisition mode';
            app.Andor19330AcquisitionmodeDropDown = uidropdown(app.Andor19330Panel);
            app.Andor19330AcquisitionmodeDropDown.Items = {'Full Frame 1', 'Cropped 100x1024 1', 'FK 2', 'FK 4', 'FK 8'};
            app.Andor19330AcquisitionmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330AcquisitionmodeDropDown.Value = 'FK 2';
            app.Andor19330TriggermodeDropDownLabel = uilabel(app.Andor19330Panel);
            app.Andor19330TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.Andor19330TriggermodeDropDown = uidropdown(app.Andor19330Panel);
            app.Andor19330TriggermodeDropDown.Items = {'External', 'Internal'};
            app.Andor19330TriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330TriggermodeDropDown.Value = 'External';
            app.Andor19330ReadoutSpeedDropDownLabel = uilabel(app.Andor19330Panel);
            app.Andor19330ReadoutSpeedDropDownLabel.Text = 'Readout speed';
            app.Andor19330ReadoutSpeedDropDown = uidropdown(app.Andor19330Panel);
            app.Andor19330ReadoutSpeedDropDown.Items = {'1MHz', '3MHz', '5MHz'};
            app.Andor19330ReadoutSpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330ReadoutSpeedDropDown.Value = '1MHz';
            app.Andor19330EstimatedreadouttimeLabel = uilabel(app.Andor19330Panel);
            app.Andor19330EstimatedreadouttimeLabel.Text = 'Estimated readout time: ';
            app.Andor19330TemperatureLabel = uilabel(app.Andor19330Panel);
            app.Andor19330TemperatureLabel.Text = 'Current Temperature:';
            app.Andor19330ConfigureButton = uibutton(app.Andor19330Panel, 'push');
            app.Andor19330ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @configureAndor19330, true);
            app.Andor19330ConfigureButton.Text = 'Configure Andor 19330';
            app.Andor19330CheckTemperatureButton = uibutton(app.Andor19330Panel, 'push');
            app.Andor19330CheckTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @checkTemperatureAndor19330, true);
            app.Andor19330CheckTemperatureButton.Text = 'Check Temperature';
            app.Andor19330CloseButton = uibutton(app.Andor19330Panel, 'push');
            app.Andor19330CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closeAndor19330, true);
            app.Andor19330CloseButton.Text = 'Close Andor 19330';
            app.Andor19331Panel = uipanel(app.CameraControlPanel);
            app.Andor19331Panel.Title = 'Andor 19331';
            app.Andor19331StatusLamp = uilamp(app.Andor19331Panel);
            app.Andor19331ExposuresSpinnerLabel = uilabel(app.Andor19331Panel);
            app.Andor19331ExposuresSpinnerLabel.Text = 'Exposure (s)';
            app.Andor19331ExposuresSpinner = uispinner(app.Andor19331Panel);
            app.Andor19331ExposuresSpinner.Step = 0.1;
            app.Andor19331ExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331ExposuresSpinner.Value = 0.2;
            app.Andor19331AcquisitionmodeDropDownLabel = uilabel(app.Andor19331Panel);
            app.Andor19331AcquisitionmodeDropDownLabel.Text = 'Acquisition mode';
            app.Andor19331AcquisitionmodeDropDown = uidropdown(app.Andor19331Panel);
            app.Andor19331AcquisitionmodeDropDown.Items = {'Full Frame 1', 'Cropped 100x1024 1', 'FK 2', 'FK 4', 'FK 8'};
            app.Andor19331AcquisitionmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331AcquisitionmodeDropDown.Value = 'FK 2';
            app.Andor19331TriggermodeDropDownLabel = uilabel(app.Andor19331Panel);
            app.Andor19331TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.Andor19331TriggermodeDropDown = uidropdown(app.Andor19331Panel);
            app.Andor19331TriggermodeDropDown.Items = {'External', 'Internal'};
            app.Andor19331TriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331TriggermodeDropDown.Value = 'External';
            app.Andor19331ReadoutSpeedDropDownLabel = uilabel(app.Andor19331Panel);
            app.Andor19331ReadoutSpeedDropDownLabel.Text = 'Readout speed';
            app.Andor19331ReadoutSpeedDropDown = uidropdown(app.Andor19331Panel);
            app.Andor19331ReadoutSpeedDropDown.Items = {'1MHz', '3MHz', '5MHz'};
            app.Andor19331ReadoutSpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331ReadoutSpeedDropDown.Value = '1MHz';
            app.Andor19331EstimatedreadouttimeLabel = uilabel(app.Andor19331Panel);
            app.Andor19331EstimatedreadouttimeLabel.Text = 'Estimated readout time: ';
            app.Andor19331TemperatureLabel = uilabel(app.Andor19331Panel);
            app.Andor19331TemperatureLabel.Text = 'Current Temperature: ';
            app.Andor19331ConfigureButton = uibutton(app.Andor19331Panel, 'push');
            app.Andor19331ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @configureAndor19331, true);
            app.Andor19331ConfigureButton.Text = 'Configure Andor 19331';
            app.Andor19331CheckTemperatureButton = uibutton(app.Andor19331Panel, 'push');
            app.Andor19331CheckTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @checkTemperatureAndor19331, true);
            app.Andor19331CheckTemperatureButton.Text = 'Check Temperature';
            app.Andor19331CloseButton = uibutton(app.Andor19331Panel, 'push');
            app.Andor19331CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closeAndor19331, true);
            app.Andor19331CloseButton.Text = 'Close Andor 19331';
            app.ZeluxPanel = uipanel(app.CameraControlPanel);
            app.ZeluxPanel.Title = 'Zelux';
            app.ZeluxStatusLamp = uilamp(app.ZeluxPanel);
            app.ZeluxExposuresSpinnerLabel = uilabel(app.ZeluxPanel);
            app.ZeluxExposuresSpinnerLabel.Text = 'Exposure (s)';
            app.ZeluxExposuresSpinner = uispinner(app.ZeluxPanel);
            app.ZeluxExposuresSpinner.Step = 1e-05;
            app.ZeluxExposuresSpinner.ValueDisplayFormat = '%11.6g';
            app.ZeluxExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @updateZeluxStatus, true);
            app.ZeluxExposuresSpinner.Value = 0.000694;
            app.ZeluxTriggermodeDropDownLabel = uilabel(app.ZeluxPanel);
            app.ZeluxTriggermodeDropDownLabel.Text = 'Trigger mode';
            app.ZeluxTriggermodeDropDown = uidropdown(app.ZeluxPanel);
            app.ZeluxTriggermodeDropDown.Items = {'External', 'Internal'};
            app.ZeluxTriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateZeluxStatus, true);
            app.ZeluxTriggermodeDropDown.Value = 'External';
            app.ZeluxEstimatedreadouttimeLabel = uilabel(app.ZeluxPanel);
            app.ZeluxEstimatedreadouttimeLabel.Text = 'Estimated readout time: ';
            app.ZeluxConfigureButton = uibutton(app.ZeluxPanel, 'push');
            app.ZeluxConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @configureZelux, true);
            app.ZeluxConfigureButton.Text = 'Configure Zelux';
            app.ZeluxCloseButton = uibutton(app.ZeluxPanel, 'push');
            app.ZeluxCloseButton.ButtonPushedFcn = createCallbackFcn(app, @closeZelux, true);
            app.ZeluxCloseButton.Text = 'Close Zelux';
            app.ZeluxMaxQueuedFramesSpinnerLabel = uilabel(app.ZeluxPanel);
            app.ZeluxMaxQueuedFramesSpinnerLabel.Text = 'Max queued frames';
            app.ZeluxMaxQueuedFramesSpinner = uispinner(app.ZeluxPanel);
            app.ZeluxMaxQueuedFramesSpinner.Limits = [0 Inf];
            app.ZeluxMaxQueuedFramesSpinner.RoundFractionalValues = 'on';
            app.ZeluxMaxQueuedFramesSpinner.ValueDisplayFormat = '%d';
            app.ZeluxMaxQueuedFramesSpinner.ValueChangedFcn = createCallbackFcn(app, @updateZeluxStatus, true);
            app.ZeluxMaxQueuedFramesSpinner.Value = 1;
            app.InstructionsLabel_4 = uilabel(app.SettingTab);
            app.InstructionsLabel_4.Text = {'- Number of Acq. (images): control the storage space for raw images, the raw '; '  images generated by this number of full sequence run will be kept in memory'; '- Number of Acq. (Statistics): control the storage space for analysis results, '; '  because the analysis usually gives only a few numbers, this number can be '; '  much larger than "Number of Acq. (images)" while still taking much smaller '; '  storage space'; '- Timeout (seconds): control the maximum wait time for acquiring a single image '; '   from any camera. If time exceeds the max but there is still no new image, the '; '   sequence will throw a warning and return an empty image'; '- Refresh interval (seconds): control the refresh interval for acquiring a single '; '  image from any camera. The camera will check if a new image is available '; '  every this interval and return the acquired image when it sees the new data.'; '- Data sampling interval: integer, control the saving interval of data acquisition. '; '  The app will save the raw images every this interval. When set to 1, it saves '; '  every raw images.'};
            app.InstructionsLabel_3 = uilabel(app.SettingTab);
            app.InstructionsLabel_3.Text = 'Instruction for setting up Acquisition Control parameters:';
            app.InstructionsLabel_2 = uilabel(app.SettingTab);
            app.InstructionsLabel_2.Text = {'- Camera: the camera to use for this step'; '    - Can be selected from {Andor19330, Andor19331, Zelux, DMD}'; '    - Note that DMD is not a real camera but a projector'; ''; '- Label: the label of the image, which is to tell the images acquired from the '; '  same cameras apart'; '    - Can be set to anything except for string with no character ""'; ''; '- Type: type of operations'; '    - Can be set to {Start, Start+Acquire, Acquire, Analysis, Project}'; '    - "Start" mode starts the acquisition, which either trigger the camera internally, '; '       or set the camera to a state that is ready to receive external trigger'; '    - "Acquire" mode acquires an image from specified camera and label it'; '    - "Start+Acquire" mode does both "Start" and "Acquire"'; '    - "Project" mode only works for camera "DMD"'; ''; '- Note: additional input parameters for the step'; '    - When in "Start"/"Acquire"/"Start+Acquire", the parameters control the '; '      acquisition from camera'; '          - For example, "Acquire, flag_immediate, min_wait = 1" will flag '; '            immediately available image as bad shot, and any acquisition with less '; '            than 1s wait time is also flagged as bad. This is useful because '; '            sometimes the analysis is taking too long and there are pending frame '; '            buffered in the camera memory, which leads to incorrect sequence '; '            synchronization.'; '          - Parameters must start with identifier, for example, "Start, verbose, '; '            Acquire, min_wait = 1, Preprocess, verbose" has "Start" and "Acquire" as '; '            identifier'; '    - When in "Analysis" mode, the parameters specify which analysis to make '; '      and the input parameters to the analysis functions'; '    - When in "Project" mode, the parameters control the projected pattern'};
            app.InstructionsLabel_1 = uilabel(app.SettingTab);
            app.InstructionsLabel_1.Text = 'Instruction for setting up SequenceTable:';
            app.TotalRunTimeLabel = uilabel(app.UIFigure);
            app.TotalRunTimeLabel.Text = 'Total Run Time:';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Stopped';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ElapsedTimeLabel = uilabel(app.UIFigure);
            app.ElapsedTimeLabel.Text = 'Elapsed Time:';
            app.RunNumLabel = uilabel(app.UIFigure);
            app.RunNumLabel.Text = 'Run Num: ';
            app.SmallAxes1Menu = uicontextmenu(app.UIFigure);
            app.SmallAxes1SaveMenu = uimenu(app.SmallAxes1Menu);
            app.SmallAxes1SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SmallAxes1SaveMenuSelected, true);
            app.SmallAxes1SaveMenu.Text = 'save';
            app.SmallAxes1ClearMenu = uimenu(app.SmallAxes1Menu);
            app.SmallAxes1ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @SmallAxes1ClearMenuSelected, true);
            app.SmallAxes1ClearMenu.Text = 'clear';
            % Assign app.SmallAxes1Menu
            app.SmallAxes1.ContextMenu = app.SmallAxes1Menu;
            app.SmallAxes2Menu = uicontextmenu(app.UIFigure);
            app.SmallAxes2SaveMenu = uimenu(app.SmallAxes2Menu);
            app.SmallAxes2SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SmallAxes2SaveMenuSelected, true);
            app.SmallAxes2SaveMenu.Text = 'save';
            app.SmallAxes2ClearMenu = uimenu(app.SmallAxes2Menu);
            app.SmallAxes2ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @SmallAxes2ClearMenuSelected, true);
            app.SmallAxes2ClearMenu.Text = 'clear';
            % Assign app.SmallAxes2Menu
            app.SmallAxes2.ContextMenu = app.SmallAxes2Menu;
            app.SmallAxes3Menu = uicontextmenu(app.UIFigure);
            app.SmallAxes3SaveMenu = uimenu(app.SmallAxes3Menu);
            app.SmallAxes3SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SmallAxes3SaveMenuSelected, true);
            app.SmallAxes3SaveMenu.Text = 'save';
            app.SmallAxes3ClearMenu = uimenu(app.SmallAxes3Menu);
            app.SmallAxes3ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @SmallAxes3ClearMenuSelected, true);
            app.SmallAxes3ClearMenu.Text = 'clear';
            % Assign app.SmallAxes3Menu
            app.SmallAxes3.ContextMenu = app.SmallAxes3Menu;
            app.SmallAxes4Menu = uicontextmenu(app.UIFigure);
            app.SmallAxes4SaveMenu = uimenu(app.SmallAxes4Menu);
            app.SmallAxes4SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SmallAxes4SaveMenuSelected, true);
            app.SmallAxes4SaveMenu.Text = 'save';
            app.SmallAxes4ClearMenu = uimenu(app.SmallAxes4Menu);
            app.SmallAxes4ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @SmallAxes4ClearMenuSelected, true);
            app.SmallAxes4ClearMenu.Text = 'clear';
            % Assign app.SmallAxes4Menu
            app.SmallAxes4.ContextMenu = app.SmallAxes4Menu;
            app.SmallAxes5Menu = uicontextmenu(app.UIFigure);
            app.SmallAxes5SaveMenu = uimenu(app.SmallAxes5Menu);
            app.SmallAxes5SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SmallAxes5SaveMenuSelected, true);
            app.SmallAxes5SaveMenu.Text = 'save';
            app.SmallAxes5ClearMenu = uimenu(app.SmallAxes5Menu);
            app.SmallAxes5ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @SmallAxes5ClearMenuSelected, true);
            app.SmallAxes5ClearMenu.Text = 'clear';
            % Assign app.SmallAxes5Menu
            app.SmallAxes5.ContextMenu = app.SmallAxes5Menu;
            app.BigAxes1Menu = uicontextmenu(app.UIFigure);
            app.BigAxes1EditcolormapMenu = uimenu(app.BigAxes1Menu);
            app.BigAxes1EditcolormapMenu.MenuSelectedFcn = createCallbackFcn(app, @BigAxes1EditcolormapMenuSelected, true);
            app.BigAxes1EditcolormapMenu.Text = 'Edit colormap';
            % Assign app.BigAxes1Menu
            app.BigAxes1.ContextMenu = app.BigAxes1Menu;
            app.BigAxes2Menu = uicontextmenu(app.UIFigure);
            app.BigAxes2EditcolormapMenu = uimenu(app.BigAxes2Menu);
            app.BigAxes2EditcolormapMenu.MenuSelectedFcn = createCallbackFcn(app, @BigAxes2EditcolormapMenuSelected, true);
            app.BigAxes2EditcolormapMenu.Text = 'Edit colormap';
            % Assign app.BigAxes2Menu
            app.BigAxes2.ContextMenu = app.BigAxes2Menu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AcquisitorApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startApp)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end